classdef Planar_Kin_Controlpanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResetIncrEncoderButton      matlab.ui.control.Button
        IECOnButton                 matlab.ui.control.Button
        RedLevDropDown              matlab.ui.control.DropDown
        ReactionPanel               matlab.ui.container.Panel
        ClampingReactionOnButton    matlab.ui.control.Button
        PrioXOnButton               matlab.ui.control.Button
        NoretractionatcontactpointButton  matlab.ui.control.Button
        d_retr_contmEditField       matlab.ui.control.EditField
        d_retr_contmEditFieldLabel  matlab.ui.control.Label
        d_retr_mPmEditField         matlab.ui.control.EditField
        d_retr_mPmEditFieldLabel    matlab.ui.control.Label
        ReactOnButton               matlab.ui.control.Button
        StiffnessNmEditField        matlab.ui.control.EditField
        StiffnessNmEditFieldLabel   matlab.ui.control.Label
        UploadReactparamButton      matlab.ui.control.Button
        ContactdetectionOnButton    matlab.ui.control.Button
        OnlyforSimulationPanel      matlab.ui.container.Panel
        InitButton                  matlab.ui.control.Button
        UploadCapselButton          matlab.ui.control.Button
        y_capsmEditField            matlab.ui.control.EditField
        y_capsmEditFieldLabel       matlab.ui.control.Label
        x_capsmEditField            matlab.ui.control.EditField
        x_capsmEditFieldLabel       matlab.ui.control.Label
        SetStatuswordButton         matlab.ui.control.Button
        ImpedanceControlPanel       matlab.ui.container.Panel
        UploadButton                matlab.ui.control.Button
        SaturationlowerBoundTorqueNmEditFieldLabel  matlab.ui.control.Label
        SaturationlowerBoundTorqueNmEditField  matlab.ui.control.EditField
        SaturationupperBoundTorqueNmLabel  matlab.ui.control.Label
        SaturationupperBoundTorqueNmEditField  matlab.ui.control.EditField
        PhiPanel                    matlab.ui.container.Panel
        KzNmradEditFieldLabel       matlab.ui.control.Label
        KzNmradEditField            matlab.ui.control.EditField
        DzEditFieldLabel            matlab.ui.control.Label
        DzEditField                 matlab.ui.control.EditField
        YPanel                      matlab.ui.container.Panel
        KyNmEditFieldLabel          matlab.ui.control.Label
        KyNmEditField               matlab.ui.control.EditField
        DyEditFieldLabel            matlab.ui.control.Label
        DyEditField                 matlab.ui.control.EditField
        XPanel                      matlab.ui.container.Panel
        KxNmEditFieldLabel          matlab.ui.control.Label
        KxNmEditField               matlab.ui.control.EditField
        DxLabel                     matlab.ui.control.Label
        DxEditField                 matlab.ui.control.EditField
        SetModelNameButton          matlab.ui.control.Button
        RunTrajectoriesPanel        matlab.ui.container.Panel
        DurationofTrajectoryPanel   matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        FileNameEditField           matlab.ui.control.EditField
        FileNameEditFieldLabel      matlab.ui.control.Label
        LoadTrajectoryButton        matlab.ui.control.Button
        TrajectoryButton            matlab.ui.control.Button
        DrivetoTargetPanel          matlab.ui.container.Panel
        TPGainEditField             matlab.ui.control.EditField
        TPGainEditFieldLabel        matlab.ui.control.Label
        PhiEditField                matlab.ui.control.EditField
        PhiEditFieldLabel           matlab.ui.control.Label
        YmEditField                 matlab.ui.control.EditField
        YmEditFieldLabel            matlab.ui.control.Label
        XmEditField                 matlab.ui.control.EditField
        XmEditFieldLabel            matlab.ui.control.Label
        TargetPoseLabel             matlab.ui.control.Label
        GotoTargetButton            matlab.ui.control.Button
        HomePoseButton              matlab.ui.control.Button
        ModelNameEditField          matlab.ui.control.EditField
        ModelNameEditFieldLabel     matlab.ui.control.Label
        EnableDriveButton           matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        ModelName; % Description
        UDP_Sender;
    end
    
    methods (Access = private)
        
        function results = enableUI_PD(app,enable)
            PD_Children = app.ImpedanceControlPanel.Children;
            for i = 1 : length(PD_Children)
                try
                    set(PD_Children(i),'Enable',enable)
                catch  
                    
                end
                try
                    set(PD_Children(i).Children,'Enable',enable);
                catch
                    
                end
                
            end     
        end
    
        function results = enableUI_Logic(app,enable)
            Logic_Children = app.DrivetoTargetPanel.Children;
            set(Logic_Children,'Enable',enable);
            if enable == false
                app.GotoTargetButton.Text= "Go to Target";
                app.HomePoseButton.Text = "Home Pose";
            end
        end
        
        function isLoaded = Traj_loaded(app)
            if sum(str2mat(get_param(app.ModelName+"/Logic/Trajectory/Velocity/XD", 'Value'))) == 0 || ...
                    sum(str2mat(get_param(app.ModelName+"/Logic/Trajectory/Velocity/YD", 'Value'))) == 0 || ...
                    sum(str2mat(get_param(app.ModelName+"/Logic/Trajectory/Velocity/PhieD", 'Value'))) == 0
                isLoaded = false;
            else
                isLoaded = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ModelNameEditField.Value = "PKM_Planar_Testbench_2018";
            app.UDP_Sender = dsp.UDPSender;

            app.XmEditField.Value = '0';
            app.YmEditField.Value = '0';
            app.PhiEditField.Value = '0';
            app.TPGainEditField.Value = '1';
            
            app.enableUI_Logic(false);
            app.enableUI_PD(false);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.ModelName)
                return
            end
            if app.StartButton.Text == "Start" 
                app.EnableDriveButton.Enable = true;
                app.ContactdetectionOnButton.Enable = true;
                set_param(app.ModelName+"/Controlpanel/EMERGENCY_OFF", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Enable_Drive", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Position", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Homepose", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_trajectory", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S1", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S2", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S3", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Trans_Drive", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/GUI_started", 'Value', '1');   
                
                app.StartButton.Text = "Stop";

                app.SetStatuswordButton.Enable = true;
                app.LoadTrajectoryButton.Enable = true;
                app.enableUI_PD(true);
                app.ResetIncrEncoderButton.Enable = true;
                app.ReactOnButton.Enable = true; 
                app.RedLevDropDown.Enable = true;  
                app.IECOnButton.Enable = true;  
                if app.ReactOnButton.Text == "On"
                    app.PrioXOnButton.Enable = true; 
                    app.UploadReactparamButton.Enable = true;
                    app.StiffnessNmEditField.Enable = true;
                    app.d_retr_mPmEditField.Enable = true;  
                    app.NoretractionatcontactpointButton.Enable = true;  
                    app.d_retr_contmEditField.Enable = true;  
                    app.ClampingReactionOnButton.Enable = true; 
                else
                    app.PrioXOnButton.Enable = false; 
                    app.UploadReactparamButton.Enable = false;
                    app.StiffnessNmEditField.Enable = false;
                    app.d_retr_mPmEditField.Enable = false;  
                    app.NoretractionatcontactpointButton.Enable = false;  
                    app.d_retr_contmEditField.Enable = false;  
                    app.ClampingReactionOnButton.Enable = false; 
                end                    
                
            else %Stop Button pressed
                set_param(app.ModelName+"/Controlpanel/EMERGENCY_OFF", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Enable_Drive", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Position", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Homepose", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_trajectory", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S1", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S2", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S3", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Trans_Drive", 'Value', '0');  
                set_param(app.ModelName+"/Controlpanel/GUI_started", 'Value', '0');
                app.StartButton.Text = "Start";
                app.EnableDriveButton.Enable = false;
                app.ContactdetectionOnButton.Enable = false;
                app.EnableDriveButton.Text = "Enable Drive";
                app.GotoTargetButton.Text= "Go to Target";
                app.HomePoseButton.Text = "Home Pose";
                app.TrajectoryButton.Text = "Trajectory";

                app.TrajectoryButton.Enable = false;
                app.SetStatuswordButton.Enable = false;
                app.LoadTrajectoryButton.Enable = false;
                app.ResetIncrEncoderButton.Enable = false; 
                app.IECOnButton.Enable = false;    
                app.UploadReactparamButton.Enable = false;
                app.StiffnessNmEditField.Enable = false;
                app.d_retr_mPmEditField.Enable = false;    
                app.ReactOnButton.Enable = false;    
                app.PrioXOnButton.Enable = false;       
                app.RedLevDropDown.Enable = false;  
                app.NoretractionatcontactpointButton.Enable = false;  
                app.d_retr_contmEditField.Enable = false;  
                app.ClampingReactionOnButton.Enable= false;
                
                app.enableUI_Logic(false);
                app.enableUI_PD(false);
            end
        end

        % Button pushed function: EnableDriveButton
        function EnableDriveButtonPushed(app, event)
            if app.EnableDriveButton.Text == "Enable Drive"
                app.enableUI_Logic(true);
                app.enableUI_PD(false);
                app.EnableDriveButton.Text = "Disable Drive";
                set_param(app.ModelName+"/Controlpanel/Enable_Drive", 'Value', '1');                
                app.LoadTrajectoryButton.Enable = false;              
                app.ContactdetectionOnButton.Enable = false;
                app.ResetIncrEncoderButton.Enable = false;
                app.FileNameEditField.Enable = false;
                
                if app.Traj_loaded() == true
                    app.TrajectoryButton.Enable = true;
                end
                pause(.1);
            else
                app.enableUI_PD(true);
                app.enableUI_Logic(false);
                set_param(app.ModelName+"/Controlpanel/Enable_Drive", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Position", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Homepose", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_trajectory", 'Value', '0');
                
                app.GotoTargetButton.Text = "Go to Target";
                app.HomePoseButton.Text = "Home Pose";
                app.TrajectoryButton.Text = "Trajectory";
                app.EnableDriveButton.Text = "Enable Drive";
                app.LoadTrajectoryButton.Enable = true;
                app.ResetIncrEncoderButton.Enable = true;
                app.TrajectoryButton.Enable = false;             
                app.ContactdetectionOnButton.Enable = true;                
                app.FileNameEditField.Enable = true;
                pause(1);
            end
        end

        % Button pushed function: SetStatuswordButton
        function SetStatuswordButtonPushed(app, event)
            pause(.1);
            set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S1", 'Value', "4672");
            set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S2", 'Value', "4672");
            set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S3", 'Value', "4672");
            set_param(app.ModelName+"/Controlpanel/Statusword_Trans_Drive", 'Value', "37984");
            pause(.1);
            set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S1", 'Value', "4609");
            set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S2", 'Value', "4609");
            set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S3", 'Value', "4609");
            set_param(app.ModelName+"/Controlpanel/Statusword_Trans_Drive", 'Value', "37937");
            pause(.1);
            set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S1", 'Value', "4643");
            set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S2", 'Value', "4643");
            set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S3", 'Value', "4643");
            set_param(app.ModelName+"/Controlpanel/Statusword_Trans_Drive", 'Value', "37939");
        end

        % Button pushed function: GotoTargetButton
        function GotoTargetButtonPushed(app, event)
            if app.GotoTargetButton.Text == "Go to Target"
                              
                x_des = str2double(app.XmEditField.Value);
                y_des = str2double(app.YmEditField.Value);
                phiz_des = str2double(app.PhiEditField.Value);
                
                if sqrt(x_des*x_des + y_des*y_des)>0.8 || abs(phiz_des)>90
                    return;
                end
                
                app.GotoTargetButton.Text = "Terminate";

                value = app.TPGainEditField.Value;
                if(str2double(value) <= 0 || str2double(value) > 20)
                    set_param(app.ModelName+"/Parameter/TP_lim_gain", 'Value', '1');
                    app.TPGainEditField.Value = 1;
                else
                    set_param(app.ModelName+"/Parameter/TP_lim_gain", 'Value', value);
                end                        
                set_param(app.ModelName+"/Controlpanel/Drive_to_Position", 'Value', '1');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Homepose", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_trajectory", 'Value', '0');
                app.HomePoseButton.Enable = false;
                app.TrajectoryButton.Enable = false;
                
                set_param(app.ModelName+"/End pose specification/XE", 'Value', app.XmEditField.Value);
                set_param(app.ModelName+"/End pose specification/YE", 'Value', app.YmEditField.Value);
                set_param(app.ModelName+"/End pose specification/PHIE", 'Value', app.PhiEditField.Value);      
                app.UploadReactparamButton.Enable = false;
                app.StiffnessNmEditField.Enable = false;
                app.d_retr_mPmEditField.Enable = false;
                app.IECOnButton.Enable = false;  
                app.ReactOnButton.Enable = false;
                app.PrioXOnButton.Enable = false; 
                app.ClampingReactionOnButton.Enable = false; 
                app.RedLevDropDown.Enable = false;   
                pause(0.1);
            else
                app.GotoTargetButton.Text = "Go to Target";
                set_param(app.ModelName+"/Controlpanel/Drive_to_Position", 'Value', '0');
                app.HomePoseButton.Enable = true;
                app.TrajectoryButton.Enable = true;      
                app.UploadReactparamButton.Enable = true;
                app.StiffnessNmEditField.Enable = true;
                app.d_retr_mPmEditField.Enable = true;
                app.ReactOnButton.Enable = true;
                app.PrioXOnButton.Enable = true; 
                app.RedLevDropDown.Enable = true;   
                app.IECOnButton.Enable = true;  
                app.ClampingReactionOnButton.Enable = true; 
                pause(0.1);

%                 app.enableUI_Logic(true);
            end

        end

        % Button pushed function: HomePoseButton
        function HomePoseButtonPushed(app, event)
            if app.HomePoseButton.Text == "Home Pose"
                
                value = app.TPGainEditField.Value;
                if(str2num(value) <= 0 || str2num(value) > 20)
                    set_param(app.ModelName+"/Parameter/TP_lim_gain", 'Value', '1');
                    app.TPGainEditField.Value = 1;
                else
                    set_param(app.ModelName+"/Parameter/TP_lim_gain", 'Value', value);
                end
                
                app.HomePoseButton.Text = "Terminate";
                set_param(app.ModelName+"/Controlpanel/Drive_to_Homepose", 'Value', '1');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Position", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_trajectory", 'Value', '0');
                app.GotoTargetButton.Enable = false;
                app.TrajectoryButton.Enable = false;
                
                set_param(app.ModelName+"/End pose specification/XE",'Value',"0");
                set_param(app.ModelName+"/End pose specification/YE",'Value',"0");
                set_param(app.ModelName+"/End pose specification/PHIE",'Value',"0");
                    
                app.UploadReactparamButton.Enable = false;
                app.StiffnessNmEditField.Enable = false;
                app.d_retr_mPmEditField.Enable = false;
                app.ReactOnButton.Enable = false;
                app.PrioXOnButton.Enable = false; 
                app.RedLevDropDown.Enable = false;   
                app.IECOnButton.Enable = false;  
                app.ClampingReactionOnButton.Enable = false; 
                pause(0.1);
                
            else
                app.HomePoseButton.Text = "Home Pose";
                set_param(app.ModelName+"/Controlpanel/Drive_to_Homepose", 'Value', '0');
                app.GotoTargetButton.Enable = true;
                app.TrajectoryButton.Enable = true;
                app.UploadReactparamButton.Enable = true;
                app.StiffnessNmEditField.Enable = true;
                app.d_retr_mPmEditField.Enable = true;
                app.ReactOnButton.Enable = true;
                app.PrioXOnButton.Enable = true; 
                app.RedLevDropDown.Enable = true;   
                app.IECOnButton.Enable = true;  
                app.ClampingReactionOnButton.Enable = true; 
                pause(0.1);
            end
        end

        % Value changed function: ModelNameEditField
        function ModelNameEditFieldValueChanged(app, event)
            
        end

        % Value changed function: TPGainEditField
        function TPGainEditFieldValueChanged(app, event)
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            if(isempty(app.ModelName))
                delete(app)
            else                          
                app.ContactdetectionOnButton.Text = "Contactdetection On";
                set_param(app.ModelName+"/Logic/Safety functions/colldect_enable", 'Value', '1');
                app.ContactdetectionOnButton.BackgroundColor = [0.39,0.83,0.07]; % green
                
                set_param(app.ModelName+"/Controlpanel/Enable_Drive", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Position", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Homepose", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S1", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S2", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Rot_Drive_S3", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Statusword_Trans_Drive", 'Value', '0');     
                set_param(app.ModelName+"/Parameter/TP_lim_gain", 'Value', '1');               
                delete(app)
            end
        end

        % Button pushed function: TrajectoryButton
        function TrajectoryButtonPushed(app, event)
            if app.Traj_loaded() == false
                return; %No Trajectory loaded
            end

            if app.TrajectoryButton.Text == "Trajectory"
                app.TrajectoryButton.Text = "Terminate";
                set_param(app.ModelName+"/Control/kappa_gain", 'Gain', num2str(100000));
                set_param(app.ModelName+"/Control/mindist_gain", 'Gain', num2str(0));
                app.IECOnButton.Text = "IEC Off";
                app.IECOnButton.BackgroundColor = [0.64,0.18,0.08]; % red
                set_param(app.ModelName+"/Controlpanel/Drive_trajectory", 'Value', '1');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Position", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_to_Homepose", 'Value', '0');
                app.enableUI_Logic(false);      
                app.UploadReactparamButton.Enable = false;
                app.StiffnessNmEditField.Enable = false;
                app.d_retr_mPmEditField.Enable = false;
                app.ReactOnButton.Enable = false;
                app.PrioXOnButton.Enable = false; 
                app.RedLevDropDown.Enable = false;   
                app.IECOnButton.Enable = false;  
                app.ClampingReactionOnButton.Enable = false; 

            else
                app.TrajectoryButton.Text = "Trajectory";
                set_param(app.ModelName+"/Controlpanel/Drive_to_Position", 'Value', '0');
                set_param(app.ModelName+"/Controlpanel/Drive_trajectory", 'Value', '0');
                app.enableUI_Logic(true);      
                app.UploadReactparamButton.Enable = true;
                app.StiffnessNmEditField.Enable = true;
                app.d_retr_mPmEditField.Enable = true;
                app.ReactOnButton.Enable = true;
                app.PrioXOnButton.Enable = true; 
                app.RedLevDropDown.Enable = true;   
                app.IECOnButton.Enable = true;  
                app.ClampingReactionOnButton.Enable = true; 
            end
        end

        % Button pushed function: LoadTrajectoryButton
        function LoadTrajectoryButtonPushed(app, event)
            if ~isempty(app.FileNameEditField)
                try
                    traj = load(strcat("Trajectories/",app.FileNameEditField.Value));
                catch ME
                    disp("Loading trajectory did not work as expected. Enter absolute path of trajectory .mat file.");
                    disp(ME);
                end
                
                    d = uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Trajectory is loading');
                    pause(.2)
                
                    XE_traj = traj.traj.XE_traj;
                    XDE_traj = traj.traj.XDE_traj;
                    XDDE_traj = traj.traj.XDDE_traj;
                    len_traj = traj.traj.len_traj;
                    
                    set_param(app.ModelName+"/Logic/Trajectory/Trajectory_Length", 'Value', num2str(len_traj));
                    d.Value = 1/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    set_param(app.ModelName+"/Logic/Trajectory/Pose/isValid", 'Value', mat2str(XE_traj(:,4)));
                    d.Value = 2/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    
                    set_param(app.ModelName+"/Logic/Trajectory/Pose/X0", 'Value', mat2str(XE_traj(1,1)));
                    d.Value = 3/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    set_param(app.ModelName+"/Logic/Trajectory/Pose/Y0", 'Value', mat2str(XE_traj(1,2)));
                    d.Value = 4/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    set_param(app.ModelName+"/Logic/Trajectory/Pose/Phie0", 'Value', mat2str(XE_traj(1,3)));
                    d.Value = 5/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    set_param(app.ModelName+"/Logic/Trajectory/Pose/X", 'Value', mat2str(XE_traj(:,1)));
                    d.Value = 6/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    set_param(app.ModelName+"/Logic/Trajectory/Pose/Y", 'Value', mat2str(XE_traj(:,2)));
                    d.Value = 7/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    set_param(app.ModelName+"/Logic/Trajectory/Pose/Phie", 'Value', mat2str(XE_traj(:,3)));
                    d.Value = 8/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    
                    
                    set_param(app.ModelName+"/Logic/Trajectory/Velocity/XD", 'Value', mat2str(XDE_traj(:,1)));
                    d.Value = 9/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    set_param(app.ModelName+"/Logic/Trajectory/Velocity/YD", 'Value', mat2str(XDE_traj(:,2)));
                    d.Value = 10/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    set_param(app.ModelName+"/Logic/Trajectory/Velocity/PhieD", 'Value', mat2str(XDE_traj(:,3)));
                    d.Value = 11/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    
                    set_param(app.ModelName+"/Logic/Trajectory/Acceleration/XDD", 'Value', mat2str(XDDE_traj(:,1)));
                    d.Value = 12/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    set_param(app.ModelName+"/Logic/Trajectory/Acceleration/YDD", 'Value', mat2str(XDDE_traj(:,2)));
                    d.Value = 13/14;
                    d.Message = 'Trajectory is loading...';
                    pause(0.01);
                    set_param(app.ModelName+"/Logic/Trajectory/Acceleration/PhieDD", 'Value', mat2str(XDDE_traj(:,3)));
                    d.Value = 14/14;
                    d.Message = 'Trajectory is loading...';
                    
                    close(d);
            end
                
        end

        % Button pushed function: SetModelNameButton
        function SetModelNameButtonPushed(app, event)
           
            value = app.ModelNameEditField.Value;
            app.ModelName = value;
            app.StartButton.Enable = true;
            app.InitButton.Enable = true;
            app.TPGainEditField.Value = num2str(eval(get_param(app.ModelName+"/Parameter/TP_lim_gain", 'Value')));
            Kd = eval(get_param(app.ModelName+"/Control/Kd", 'Value'));
            Dd = eval(get_param(app.ModelName+"/Control/Dd", 'Value'));
            
            app.KxNmEditField.Value = num2str(Kd(1,1));
            app.DxEditField.Value = num2str(Dd(1,1));
            
            app.KyNmEditField.Value = num2str(Kd(2,2));
            app.DyEditField.Value = num2str(Dd(2,2));
            
            app.KzNmradEditField.Value = num2str(Kd(3,3));
            app.DzEditField.Value = num2str(Dd(3,3));
            
            app.StiffnessNmEditField.Value = num2str(Kd(1,1));
            
            app.SaturationlowerBoundTorqueNmEditField.Value = get_param(app.ModelName+"/Control/PD_Saturation_X", 'LowerLimit');
            app.SaturationupperBoundTorqueNmEditField.Value = get_param(app.ModelName+"/Control/PD_Saturation_X", 'UpperLimit');
            
            app.XmEditField.Value = get_param(app.ModelName+"/End pose specification/XE", 'Value');
            app.YmEditField.Value = get_param(app.ModelName+"/End pose specification/YE", 'Value');
            app.PhiEditField.Value = get_param(app.ModelName+"/End pose specification/PHIE", 'Value');
            app.d_retr_mPmEditField.Value = get_param(app.ModelName+"/Logic/d_retr_m", 'Value');
            app.d_retr_contmEditField.Value = get_param(app.ModelName+"/Logic/d_retr_m1", 'Value');
            
            
            collreact_enable = eval(get_param(app.ModelName+"/Observer/collreact_enable", 'Value'));
            if collreact_enable
                app.ReactOnButton.Text = "On";
                app.ReactOnButton.BackgroundColor = [0.39,0.83,0.07]; % green                   
            else
                app.ReactOnButton.Text = "Off";
                app.ReactOnButton.BackgroundColor = [0.64,0.18,0.08]; % red    
            end
            
            priority_x = eval(get_param(app.ModelName+"/Control/priority_x", 'Value'));
            if priority_x
                app.PrioXOnButton.Text = "Prio-X On";
                app.PrioXOnButton.BackgroundColor = [0.39,0.83,0.07]; % green                   
            else
                app.PrioXOnButton.Text = "Prio-X Off";
                app.PrioXOnButton.BackgroundColor = [0.64,0.18,0.08]; % red    
            end

            clampingreaction = eval(get_param(app.ModelName+"/Control/clampingreaction_active", 'Value'));
            if clampingreaction
                app.ClampingReactionOnButton.Text = "Clamping Reaction On";
                app.ClampingReactionOnButton.BackgroundColor = [0.39,0.83,0.07]; % green                   
            else
                app.ClampingReactionOnButton.Text = "Clamping Reaction Off";
                app.ClampingReactionOnButton.BackgroundColor = [0.64,0.18,0.08]; % red    
            end
            
            Kd_rueck = eval(get_param(app.ModelName+"/Control/Kd_react", 'Value'));
            app.StiffnessNmEditField.Value = num2str(Kd_rueck(1,1));
            
            colldet_enable = eval(get_param(app.ModelName+"/Logic/Safety functions/colldect_enable", 'Value'));
            if colldet_enable
                app.ContactdetectionOnButton.Text = "Contactdetection On";
                app.ContactdetectionOnButton.BackgroundColor = [0.39,0.83,0.07]; % green                
            else
                app.ContactdetectionOnButton.Text = "Contactdetection Off";
                app.ContactdetectionOnButton.BackgroundColor = [0.64,0.18,0.08]; % red                
            end         
            
            rr_level = eval(get_param(app.ModelName+"/Controlpanel/rr_level", 'Value'));
            retr_CS0 = eval(get_param(app.ModelName+"/Controlpanel/retr_CS0", 'Value'));
            
            if rr_level == 0
                app.RedLevDropDown.Value = "Impedance control without RR";
            elseif rr_level == 1
                app.RedLevDropDown.Value = "RR Velocity";
            elseif rr_level == 2
                app.RedLevDropDown.Value = "RR Acceleration";
            elseif rr_level == 3
                app.RedLevDropDown.Value = "RR Torque";
            end
            
            if retr_CS0 == 1
                app.NoretractionatcontactpointButton.Text = "No retraction at contact point";
                app.NoretractionatcontactpointButton.BackgroundColor = [0.64,0.08,0.18]; % red   
            elseif retr_CS0 == 3
                app.NoretractionatcontactpointButton.Text = "Retraction at contact point in CS0";
                app.NoretractionatcontactpointButton.BackgroundColor = [0.00,0.45,0.74]; % blue                
            elseif retr_CS0 == 2
                app.NoretractionatcontactpointButton.Text = "Retraction at contact point in CSC";
                app.NoretractionatcontactpointButton.BackgroundColor = [.3,0.75,0.95]; % blue
            end

            if app.RedLevDropDown.Value == "Impedance control without RR"
                set_param(app.ModelName+"/Controlpanel/rr_level", 'Value', '0');
            elseif app.RedLevDropDown.Value == "RR Velocity"
                set_param(app.ModelName+"/Controlpanel/rr_level", 'Value', '1');
            elseif app.RedLevDropDown.Value == "RR Acceleration"
                set_param(app.ModelName+"/Controlpanel/rr_level", 'Value', '2');
            elseif app.RedLevDropDown.Value == "RR Torque"
                set_param(app.ModelName+"/Controlpanel/rr_level", 'Value', '3');
            end      
            
            set_param(app.ModelName+"/Control/kappa_gain", 'Gain', num2str(100000));
            set_param(app.ModelName+"/Control/mindist_gain", 'Gain', num2str(0));
            app.IECOnButton.Text = "IEC Off";
            app.IECOnButton.BackgroundColor = [0.64,0.18,0.08]; % red
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            Kx = app.KxNmEditField.Value;
            Dx = app.DxEditField.Value;
            
            Ky = app.KyNmEditField.Value;
            Dy = app.DyEditField.Value;
            
            Kz = app.KzNmradEditField.Value;
            Dz = app.DzEditField.Value;
            
            sat_low_bound = app.SaturationlowerBoundTorqueNmEditField.Value;
            sat_up_bound = app.SaturationupperBoundTorqueNmEditField.Value;
            
            if isempty(Kx) || isempty(Ky) || isempty(Kz) || isempty(Dx) || isempty(Dy) || isempty(Dz) || isempty(sat_low_bound) || isempty(sat_up_bound)
                return;
            end
            
            if str2double(Kx)<=0 || str2double(Ky)<=0 || str2double(Kz)<=0 || ...
               str2double(Dx) <=0 || str2double(Dy)<=0 || str2double(Dz)<=0 || ...
               str2double(sat_low_bound)>=0 || str2double(sat_up_bound)<=0
                return;
            end

            Kd = diag([str2num(Kx) str2num(Ky) str2num(Kz)]);
            Dd = diag([str2num(Dx) str2num(Dy) str2num(Dz)]);
                       
            set_param(app.ModelName+"/Control/Kd", 'Value', mat2str(Kd));
            set_param(app.ModelName+"/Control/Dd", 'Value', mat2str(Dd));
            set_param(app.ModelName+"/Control/PD_Saturation_X", 'LowerLimit', num2str(sat_low_bound));
            set_param(app.ModelName+"/Control/PD_Saturation_X", 'UpperLimit', num2str(sat_up_bound));
        end

        % Button pushed function: ContactdetectionOnButton
        function ContactdetectionOnButtonPushed(app, event)
            if app.ContactdetectionOnButton.Text == "Contactdetection On"
                app.ContactdetectionOnButton.Text = "Contactdetection Off";
                set_param(app.ModelName+"/Logic/Safety functions/colldect_enable", 'Value', '0');
                app.ContactdetectionOnButton.BackgroundColor = [0.64,0.18,0.08]; % red
            else
                app.ContactdetectionOnButton.Text = "Contactdetection On";
                set_param(app.ModelName+"/Logic/Safety functions/colldect_enable", 'Value', '1');
                app.ContactdetectionOnButton.BackgroundColor = [0.39,0.83,0.07]; % green
            end

        end

        % Button pushed function: UploadCapselButton
        function UploadCapselButtonPushed(app, event)
                xcaps = str2double(app.x_capsmEditField.Value);
                ycaps = str2double(app.y_capsmEditField.Value);
                set_param(app.ModelName+"/DirectDynamics_MuJoCo/Capsuleposition", 'Value', mat2str([xcaps;0;ycaps;0]));
        end

        % Button pushed function: UploadReactparamButton
        function UploadReactparamButtonPushed(app, event)
            K_react = app.StiffnessNmEditField.Value;  
            d_retr = app.d_retr_mPmEditField.Value;
            d_retr_link = app.d_retr_contmEditField.Value;
                        
            if isempty(K_react) || isempty(d_retr) 
                return;
            end
            
            if str2double(K_react)<0 || str2double(d_retr)<0 || str2double(d_retr_link)<0 ||...
               str2double(K_react)>2000 || str2double(d_retr)>0.4 || str2double(d_retr_link)>0.2
                return;
            end
            
            Kd = eval(get_param(app.ModelName+"/Control/Kd", 'Value'));            
            Kd(1,1) = str2double(K_react); 
            Kd(2,2) = str2double(K_react);
                       
            set_param(app.ModelName+"/Control/Kd_react", 'Value', mat2str(Kd));
            set_param(app.ModelName+"/Logic/d_retr_m", 'Value', num2str(d_retr));
            set_param(app.ModelName+"/Logic/d_retr_m1", 'Value', num2str(d_retr_link));
            
        end

        % Button pushed function: ReactOnButton
        function ReactOnButtonPushed(app, event)
            if app.ReactOnButton.Text == "On" % Off
                set_param(app.ModelName+"/Observer/collreact_enable", 'Value', num2str(0));
                set_param(app.ModelName+"/Control/priority_x", 'Value', num2str(0));
                app.ReactOnButton.Text = "Off";
                app.ReactOnButton.BackgroundColor = [0.64,0.18,0.08]; % red      
                app.PrioXOnButton.Enable = false;
                app.UploadReactparamButton.Enable = false;
                app.StiffnessNmEditField.Enable = false;
                app.d_retr_mPmEditField.Enable = false;  
                app.NoretractionatcontactpointButton.Enable = false;      
                app.d_retr_contmEditField.Enable = false;  
                app.ClampingReactionOnButton.Enable = false;
            else % On
                set_param(app.ModelName+"/Observer/collreact_enable", 'Value', num2str(1));
                app.ReactOnButton.Text = "On";
                app.ReactOnButton.BackgroundColor = [0.39,0.83,0.07]; % green   
                app.PrioXOnButton.Enable = true;
                app.UploadReactparamButton.Enable = true;
                app.StiffnessNmEditField.Enable = true;
                app.d_retr_mPmEditField.Enable = true;  
                app.NoretractionatcontactpointButton.Enable = true;  
                app.d_retr_contmEditField.Enable = true;     
                app.ClampingReactionOnButton.Enable = true;        
            end
                
        end

        % Button pushed function: NoretractionatcontactpointButton
        function NoretractionatcontactpointButtonPushed(app, event)
            if app.NoretractionatcontactpointButton.Text == "No retraction at contact point" 
                set_param(app.ModelName+"/Controlpanel/retr_CS0", 'Value', num2str(2));
                app.NoretractionatcontactpointButton.Text = "Retraction at contact point in CSC";
                app.NoretractionatcontactpointButton.BackgroundColor = [.3,0.75,0.95]; % blau
            elseif app.NoretractionatcontactpointButton.Text == "Retraction at contact point in CSC" 
                set_param(app.ModelName+"/Controlpanel/retr_CS0", 'Value', num2str(3));
                app.NoretractionatcontactpointButton.Text = "Retraction at contact point in CS0";
                app.NoretractionatcontactpointButton.BackgroundColor = [0.00,0.45,0.74]; % blau                
            elseif app.NoretractionatcontactpointButton.Text == "Retraction at contact point in CS0"
                set_param(app.ModelName+"/Controlpanel/retr_CS0", 'Value', num2str(1));
                app.NoretractionatcontactpointButton.Text = "No retraction at contact point";
                app.NoretractionatcontactpointButton.BackgroundColor = [0.64,0.08,0.18]; % red                
            end
        end

        % Value changed function: RedLevDropDown
        function RedLevDropDownValueChanged(app, event)
            value = app.RedLevDropDown.Value;
            if value == "Impedance control without RR"
                set_param(app.ModelName+"/Controlpanel/rr_level", 'Value', '0');
            elseif app.RedLevDropDown.Value == "RR Velocity"
                set_param(app.ModelName+"/Controlpanel/rr_level", 'Value', '1');
            elseif app.RedLevDropDown.Value == "RR Acceleration"
                set_param(app.ModelName+"/Controlpanel/rr_level", 'Value', '2');
            elseif app.RedLevDropDown.Value == "RR Torque"
                set_param(app.ModelName+"/Controlpanel/rr_level", 'Value', '3');
            end            
        end

        % Button pushed function: PrioXOnButton
        function PrioXOnButtonPushed(app, event)
            if app.PrioXOnButton.Text == "Prio-X On" % Off
                set_param(app.ModelName+"/Control/priority_x", 'Value', num2str(0));
                app.PrioXOnButton.Text = "Prio-X Off";
                app.PrioXOnButton.BackgroundColor = [0.64,0.18,0.08]; % red
            else % On
                set_param(app.ModelName+"/Control/priority_x", 'Value', num2str(1));
                app.PrioXOnButton.Text = "Prio-X On";
                app.PrioXOnButton.BackgroundColor = [0.39,0.83,0.07]; % green        
            end
                
        end

        % Button pushed function: IECOnButton
        function IECOnButtonPushed(app, event)
            if app.IECOnButton.Text == "IEC On" % Aus
                set_param(app.ModelName+"/Control/kappa_gain", 'Gain', num2str(100000));
                set_param(app.ModelName+"/Control/mindist_gain", 'Gain', num2str(0));
                app.IECOnButton.Text = "IEC Off";
                app.IECOnButton.BackgroundColor = [0.64,0.18,0.08]; % red
            else % An
                set_param(app.ModelName+"/Control/kappa_gain", 'Gain', num2str(1));
                set_param(app.ModelName+"/Control/mindist_gain", 'Gain', num2str(1));
                app.IECOnButton.Text = "IEC On";
                app.IECOnButton.BackgroundColor = [0.39,0.83,0.07]; % green        
            end
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            run("PKM_Planar_Testbench_2018_Init.m");
        end

        % Button pushed function: ClampingReactionOnButton
        function ClampingReactionOnButtonPushed(app, event)
            if app.ClampingReactionOnButton.Text == "Clamping Reaction On" % Off
                set_param(app.ModelName+"/Control/clampingreaction_active", 'Value', num2str(0));
                app.ClampingReactionOnButton.Text = "Clamping Reaction Off";
                app.ClampingReactionOnButton.BackgroundColor = [0.64,0.18,0.08]; % red
            else % Off
                set_param(app.ModelName+"/Control/clampingreaction_active", 'Value', num2str(1));
                app.ClampingReactionOnButton.Text = "Clamping Reaction On";
                app.ClampingReactionOnButton.BackgroundColor = [0.39,0.83,0.07]; % green        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.EnableDriveButton = uibutton(app.UIFigure, 'push');
            app.EnableDriveButton.ButtonPushedFcn = createCallbackFcn(app, @EnableDriveButtonPushed, true);
            app.EnableDriveButton.Enable = 'off';
            app.EnableDriveButton.Text = 'Enable Drive';
            app.ModelNameEditFieldLabel = uilabel(app.UIFigure);
            app.ModelNameEditFieldLabel.Text = 'Model Name';
            app.ModelNameEditField = uieditfield(app.UIFigure, 'text');
            app.ModelNameEditField.ValueChangedFcn = createCallbackFcn(app, @ModelNameEditFieldValueChanged, true);
            app.DrivetoTargetPanel = uipanel(app.UIFigure);
            app.DrivetoTargetPanel.Title = 'Drive to Target';
            app.HomePoseButton = uibutton(app.DrivetoTargetPanel, 'push');
            app.HomePoseButton.ButtonPushedFcn = createCallbackFcn(app, @HomePoseButtonPushed, true);
            app.HomePoseButton.Enable = 'off';
            app.HomePoseButton.Text = 'Home Pose';
            app.GotoTargetButton = uibutton(app.DrivetoTargetPanel, 'push');
            app.GotoTargetButton.ButtonPushedFcn = createCallbackFcn(app, @GotoTargetButtonPushed, true);
            app.GotoTargetButton.Enable = 'off';
            app.GotoTargetButton.Text = 'Go to Target';
            app.TargetPoseLabel = uilabel(app.DrivetoTargetPanel);
            app.TargetPoseLabel.Text = 'Target Pose';
            app.XmEditFieldLabel = uilabel(app.DrivetoTargetPanel);
            app.XmEditFieldLabel.Text = 'X [m]';
            app.XmEditField = uieditfield(app.DrivetoTargetPanel, 'text');
            app.YmEditFieldLabel = uilabel(app.DrivetoTargetPanel);
            app.YmEditFieldLabel.Text = 'Y [m]';
            app.YmEditField = uieditfield(app.DrivetoTargetPanel, 'text');
            app.PhiEditFieldLabel = uilabel(app.DrivetoTargetPanel);
            app.PhiEditFieldLabel.Text = 'Phi [°]';
            app.PhiEditField = uieditfield(app.DrivetoTargetPanel, 'text');
            app.TPGainEditFieldLabel = uilabel(app.DrivetoTargetPanel);
            app.TPGainEditFieldLabel.Text = 'TP Gain';
            app.TPGainEditField = uieditfield(app.DrivetoTargetPanel, 'text');
            app.TPGainEditField.ValueChangedFcn = createCallbackFcn(app, @TPGainEditFieldValueChanged, true);
            app.RunTrajectoriesPanel = uipanel(app.UIFigure);
            app.RunTrajectoriesPanel.Title = 'Run Trajectories';
            app.TrajectoryButton = uibutton(app.RunTrajectoriesPanel, 'push');
            app.TrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @TrajectoryButtonPushed, true);
            app.TrajectoryButton.Enable = 'off';
            app.TrajectoryButton.Text = 'Trajectory';
            app.LoadTrajectoryButton = uibutton(app.RunTrajectoriesPanel, 'push');
            app.LoadTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrajectoryButtonPushed, true);
            app.LoadTrajectoryButton.Enable = 'off';
            app.LoadTrajectoryButton.Text = 'Load Trajectory';
            app.FileNameEditFieldLabel = uilabel(app.RunTrajectoriesPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.RunTrajectoriesPanel, 'text');
            app.DurationofTrajectoryPanel = uipanel(app.RunTrajectoriesPanel);
            app.DurationofTrajectoryPanel.Title = 'Duration of Trajectory';
            app.Label = uilabel(app.DurationofTrajectoryPanel);
            app.Label.Text = '';
            app.SetModelNameButton = uibutton(app.UIFigure, 'push');
            app.SetModelNameButton.ButtonPushedFcn = createCallbackFcn(app, @SetModelNameButtonPushed, true);
            app.SetModelNameButton.Text = 'Set Model Name';
            app.ImpedanceControlPanel = uipanel(app.UIFigure);
            app.ImpedanceControlPanel.Title = 'Impedance Control';
            app.XPanel = uipanel(app.ImpedanceControlPanel);
            app.XPanel.Title = 'X';
            app.DxEditField = uieditfield(app.XPanel, 'text');
            app.DxEditField.Enable = 'off';
            app.DxLabel = uilabel(app.XPanel);
            app.DxLabel.Enable = 'off';
            app.DxLabel.Text = 'Dx [-]';
            app.KxNmEditField = uieditfield(app.XPanel, 'text');
            app.KxNmEditField.Enable = 'off';
            app.KxNmEditFieldLabel = uilabel(app.XPanel);
            app.KxNmEditFieldLabel.Enable = 'off';
            app.KxNmEditFieldLabel.Text = 'Kx [N/m]';
            app.YPanel = uipanel(app.ImpedanceControlPanel);
            app.YPanel.Title = 'Y';
            app.DyEditField = uieditfield(app.YPanel, 'text');
            app.DyEditField.Enable = 'off';
            app.DyEditFieldLabel = uilabel(app.YPanel);
            app.DyEditFieldLabel.Enable = 'off';
            app.DyEditFieldLabel.Text = 'Dy  [-]';
            app.KyNmEditField = uieditfield(app.YPanel, 'text');
            app.KyNmEditField.Enable = 'off';
            app.KyNmEditFieldLabel = uilabel(app.YPanel);
            app.KyNmEditFieldLabel.Enable = 'off';
            app.KyNmEditFieldLabel.Text = 'Ky [N/m]';
            app.PhiPanel = uipanel(app.ImpedanceControlPanel);
            app.PhiPanel.Title = 'Phi';
            app.DzEditField = uieditfield(app.PhiPanel, 'text');
            app.DzEditField.Enable = 'off';
            app.DzEditFieldLabel = uilabel(app.PhiPanel);
            app.DzEditFieldLabel.Enable = 'off';
            app.DzEditFieldLabel.Text = 'Dz  [-]';
            app.KzNmradEditField = uieditfield(app.PhiPanel, 'text');
            app.KzNmradEditField.Enable = 'off';
            app.KzNmradEditFieldLabel = uilabel(app.PhiPanel);
            app.KzNmradEditFieldLabel.Enable = 'off';
            app.KzNmradEditFieldLabel.Text = 'Kz [Nm/rad]';
            app.SaturationupperBoundTorqueNmEditField = uieditfield(app.ImpedanceControlPanel, 'text');
            app.SaturationupperBoundTorqueNmEditField.Enable = 'off';
            app.SaturationupperBoundTorqueNmLabel = uilabel(app.ImpedanceControlPanel);
            app.SaturationupperBoundTorqueNmLabel.Enable = 'off';
            app.SaturationupperBoundTorqueNmLabel.Text = {'Saturation '; 'upper Bound'; 'Torque [Nm]'};
            app.SaturationlowerBoundTorqueNmEditField = uieditfield(app.ImpedanceControlPanel, 'text');
            app.SaturationlowerBoundTorqueNmEditField.Enable = 'off';
            app.SaturationlowerBoundTorqueNmEditFieldLabel = uilabel(app.ImpedanceControlPanel);
            app.SaturationlowerBoundTorqueNmEditFieldLabel.Enable = 'off';
            app.SaturationlowerBoundTorqueNmEditFieldLabel.Text = {'Saturation '; 'lower Bound '; 'Torque [Nm]'};
            app.UploadButton = uibutton(app.ImpedanceControlPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Enable = 'off';
            app.UploadButton.Text = 'Upload';
            app.OnlyforSimulationPanel = uipanel(app.UIFigure);
            app.OnlyforSimulationPanel.Title = 'Only for Simulation';
            app.SetStatuswordButton = uibutton(app.OnlyforSimulationPanel, 'push');
            app.SetStatuswordButton.ButtonPushedFcn = createCallbackFcn(app, @SetStatuswordButtonPushed, true);
            app.SetStatuswordButton.Enable = 'off';
            app.SetStatuswordButton.Text = 'Set Statusword';
            app.x_capsmEditFieldLabel = uilabel(app.OnlyforSimulationPanel);
            app.x_capsmEditFieldLabel.Text = 'x_caps [m]';
            app.x_capsmEditField = uieditfield(app.OnlyforSimulationPanel, 'text');
            app.x_capsmEditField.Value = '0';
            app.y_capsmEditFieldLabel = uilabel(app.OnlyforSimulationPanel);
            app.y_capsmEditFieldLabel.Text = 'y_caps [m]';
            app.y_capsmEditField = uieditfield(app.OnlyforSimulationPanel, 'text');
            app.y_capsmEditField.Value = '0';
            app.UploadCapselButton = uibutton(app.OnlyforSimulationPanel, 'push');
            app.UploadCapselButton.ButtonPushedFcn = createCallbackFcn(app, @UploadCapselButtonPushed, true);
            app.UploadCapselButton.Text = 'Upload Capsel';
            app.InitButton = uibutton(app.OnlyforSimulationPanel, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Enable = 'off';
            app.InitButton.Text = 'Init';
            app.ContactdetectionOnButton = uibutton(app.UIFigure, 'push');
            app.ContactdetectionOnButton.ButtonPushedFcn = createCallbackFcn(app, @ContactdetectionOnButtonPushed, true);
            app.ContactdetectionOnButton.Enable = 'off';
            app.ContactdetectionOnButton.Text = 'Contactdetection On';
            app.ReactionPanel = uipanel(app.UIFigure);
            app.ReactionPanel.Title = 'Reaction';
            app.UploadReactparamButton = uibutton(app.ReactionPanel, 'push');
            app.UploadReactparamButton.ButtonPushedFcn = createCallbackFcn(app, @UploadReactparamButtonPushed, true);
            app.UploadReactparamButton.Enable = 'off';
            app.UploadReactparamButton.Text = 'Upload Reactparam';
            app.StiffnessNmEditFieldLabel = uilabel(app.ReactionPanel);
            app.StiffnessNmEditFieldLabel.Text = 'Stiffness [N/m]';
            app.StiffnessNmEditField = uieditfield(app.ReactionPanel, 'text');
            app.StiffnessNmEditField.Enable = 'off';
            app.ReactOnButton = uibutton(app.ReactionPanel, 'push');
            app.ReactOnButton.ButtonPushedFcn = createCallbackFcn(app, @ReactOnButtonPushed, true);
            app.ReactOnButton.Enable = 'off';
            app.ReactOnButton.Text = 'On';
            app.d_retr_mPmEditFieldLabel = uilabel(app.ReactionPanel);
            app.d_retr_mPmEditFieldLabel.Text = 'd_retr_mP [m]';
            app.d_retr_mPmEditField = uieditfield(app.ReactionPanel, 'text');
            app.d_retr_mPmEditField.Enable = 'off';
            app.d_retr_contmEditFieldLabel = uilabel(app.ReactionPanel);
            app.d_retr_contmEditFieldLabel.Text = 'd_retr_cont [m]';
            app.d_retr_contmEditField = uieditfield(app.ReactionPanel, 'text');
            app.d_retr_contmEditField.Enable = 'off';
            app.NoretractionatcontactpointButton = uibutton(app.ReactionPanel, 'push');
            app.NoretractionatcontactpointButton.ButtonPushedFcn = createCallbackFcn(app, @NoretractionatcontactpointButtonPushed, true);
            app.NoretractionatcontactpointButton.Enable = 'off';
            app.NoretractionatcontactpointButton.Text = 'No retraction at contact point';
            app.PrioXOnButton = uibutton(app.ReactionPanel, 'push');
            app.PrioXOnButton.ButtonPushedFcn = createCallbackFcn(app, @PrioXOnButtonPushed, true);
            app.PrioXOnButton.Enable = 'off';
            app.PrioXOnButton.Text = 'Prio-X On';
            app.ClampingReactionOnButton = uibutton(app.ReactionPanel, 'push');
            app.ClampingReactionOnButton.ButtonPushedFcn = createCallbackFcn(app, @ClampingReactionOnButtonPushed, true);
            app.ClampingReactionOnButton.Enable = 'off';
            app.ClampingReactionOnButton.Text = 'Clamping Reaction On';
            app.RedLevDropDown = uidropdown(app.UIFigure);
            app.RedLevDropDown.Items = {'Impedance control without RR', 'RR Velocity', 'RR Acceleration', 'RR Torque'};
            app.RedLevDropDown.ValueChangedFcn = createCallbackFcn(app, @RedLevDropDownValueChanged, true);
            app.RedLevDropDown.Enable = 'off';
            app.RedLevDropDown.Value = 'Impedance control without RR';
            app.IECOnButton = uibutton(app.UIFigure, 'push');
            app.IECOnButton.ButtonPushedFcn = createCallbackFcn(app, @IECOnButtonPushed, true);
            app.IECOnButton.Enable = 'off';
            app.IECOnButton.Text = 'IEC On';
            app.ResetIncrEncoderButton = uibutton(app.UIFigure, 'push');
            app.ResetIncrEncoderButton.Enable = 'off';
            app.ResetIncrEncoderButton.Text = 'Reset Incr. Encoder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Planar_Kin_Controlpanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end